classdef SensorLocator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ContourCheckBox              matlab.ui.control.CheckBox
        ErrorListBox                 matlab.ui.control.ListBox
        ErrorListBoxLabel            matlab.ui.control.Label
        TrueDataDropDown             matlab.ui.control.DropDown
        TrueDataDropDownLabel        matlab.ui.control.Label
        PixelsperInchEditField       matlab.ui.control.NumericEditField
        PixelsperInchEditFieldLabel  matlab.ui.control.Label
        UpscaledDataPanel            matlab.ui.container.Panel
        RawDataPanel                 matlab.ui.container.Panel
        RunAllButton                 matlab.ui.control.Button
        SensorNumberDropDown         matlab.ui.control.DropDown
        SensorNumberDropDownLabel    matlab.ui.control.Label
        DesciptionViewerLabel        matlab.ui.control.Label
        DataNameViewerLabel          matlab.ui.control.Label
        SensorLocatorLabel           matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        collectApp; % Main App
        currentPointHeat; % Description
        loadSet; %Data
        unitsSet;
        unitsSet2;
        pixelsPerInch; %Used for the interpolated set
        h2; %Second heat map
        trueData; %For plotting true sensor locations
        highlightSensor; %scatter plot item for identifying the sensor
        highlightContour; %Contour plot of highlighted object
    end
    
    methods (Access = private)
        
        
        function results = updatePanels(app)
            %%%%%%%% Raw Data %%%%%%%%%%%
            currP = 1;

            %Check if logged with flipped data
            if app.loadSet.SkinDataSet.flippedLength
                tSet = app.loadSet.SkinDataSet.resLength:-1:1;
            else 
                tSet = 1:app.loadSet.SkinDataSet.resLength;
            end

            for t = tSet
                for r = 1:app.loadSet.SkinDataSet.resWidth
                    cData(r,t) = app.loadSet.SkinDataSet.dataRaw(currP).avgData(str2num(app.SensorNumberDropDown.Value));
                    currP = currP+1;
                end
            end
            h = heatmap(app.RawDataPanel, cData, 'GridVisible','off', 'Colormap', hot, 'CellLabelColor','none');

            %%%%%%% Following square box code from Adam Danz %%%%%%%%%%
            % Originial Forum: https://www.mathworks.com/matlabcentral/answers/481666-heatmap-chart-depict-squares-rather-than-rectangles

            if app.unitsSet == 0
                % Temporarily change axis units 
                originalUnits = h.Units;  % save original units (probaly normalized)
                h.Units = 'inches';  % any unit that will result in squares
                % Get number of rows & columns
                sz = size(h.ColorData); 
                % Change axis size & position;
                originalPos = h.Position; 
                % make axes square (not the table cells, just the axes)
                h.Position(3:4) = min(h.Position(3:4))*[1,1]; 
                if sz(1)>sz(2)
                    % make the axis size more narrow and re-center
                    h.Position(3) = h.Position(3)*(sz(2)/sz(1)); 
                    h.Position(1) = (originalPos(1)+originalPos(3)/2)-(h.Position(3)/2);
                else
                    % make the axis size shorter and re-center
                    h.Position(4) = h.Position(4)*(sz(1)/sz(2));
                    h.Position(2) = (originalPos(2)+originalPos(4)/2)-(h.Position(4)/2);
                end
                % Return axis to original units
                h.Units = originalUnits; 
                app.unitsSet = 1;
            end

            %%%%%% Interpolated Data %%%%%%%%%
            F = scatteredInterpolant(app.loadSet.SkinDataSet.posLinAvgReal(1,:)', app.loadSet.SkinDataSet.posLinAvgReal(2,:)', app.loadSet.SkinDataSet.dataAvgsLin(str2num(app.SensorNumberDropDown.Value), :)');
            F.Method = 'natural';
            newX = (0:(1/app.PixelsperInchEditField.Value):app.loadSet.SkinDataSet.lengthIn);
            newY = (app.loadSet.SkinDataSet.widthIn:-(1/app.PixelsperInchEditField.Value):0);
            [X,Y] = meshgrid(newX, newY); %Make new locations to interpolate at
            xlin = X(:); %Flatten the matrix
            ylin = Y(:);
            DataInterp = F([xlin, ylin]); %evaluate interpolation

            %For testing interpolation
            %save("testing.mat", "DataInterp", "F", "newX", "newY", "X", "Y");

            %Organizing data into heat map
            currP = 1;
            for x = 1:length(newX)
                for y = 1:length(newY)
                    cDataInterp(y,x) = DataInterp(currP);
                    currP = currP + 1;
                end
            end
            app.h2 = heatmap(app.UpscaledDataPanel, cDataInterp, 'GridVisible','off', 'Colormap', hot, 'CellLabelColor','none');
            
            %%%%%%%%%%% Determining the sensor location %%%%%%%%%%
            [~, sensorPosIndex] = max(DataInterp);
            results = [X(sensorPosIndex), Y(sensorPosIndex)];


            %Remove X and Y ticks
            labels = app.h2.XDisplayLabels;                                 
            app.h2.XDisplayLabels = repmat(' ',size(labels,1), size(labels,2));
            
            %%%%%%% Following square box code from Adam Danz %%%%%%%%%%
            % Originial Forum: https://www.mathworks.com/matlabcentral/answers/481666-heatmap-chart-depict-squares-rather-than-rectangles

            if app.unitsSet2 == 0
                % Temporarily change axis units 
                originalUnits = app.h2.Units;  % save original units (probaly normalized)
                app.h2.Units = 'inches';  % any unit that will result in squares
                % Get number of rows & columns
                sz = size(app.h2.ColorData); 
                % Change axis size & position;
                originalPos = app.h2.Position; 
                % make axes square (not the table cells, just the axes)
                app.h2.Position(3:4) = min(app.h2.Position(3:4))*[1,1]; 
                if sz(1)>sz(2)
                    % make the axis size more narrow and re-center
                    app.h2.Position(3) = app.h2.Position(3)*(sz(2)/sz(1)); 
                    app.h2.Position(1) = (originalPos(1)+originalPos(3)/2)-(app.h2.Position(3)/2);
                else
                    % make the axis size shorter and re-center
                    app.h2.Position(4) = app.h2.Position(4)*(sz(1)/sz(2));
                    app.h2.Position(2) = (originalPos(2)+originalPos(4)/2)-(app.h2.Position(4)/2);
                end
                % Return axis to original units
                app.h2.Units = originalUnits; 
                app.unitsSet2 = 1;
            end

            %%%%%% Countour Plot %%%%%%%%
            if app.ContourCheckBox.Value
                hold(app.UIAxes, "on");
                if ~isempty(app.highlightContour)
                    delete(app.highlightContour);
                end
                colormap(app.UIAxes, jet);
                [~,app.highlightContour] = contour(app.UIAxes, X, Y, cDataInterp, 10, 'LineWidth', 2);
                hold(app.UIAxes, "off");
            end

            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, dataSet, dataName)
            app.currentPointHeat = 1;
            app.collectApp = caller;
            app.loadSet = dataSet;
            app.DataNameViewerLabel.Text = dataName;
            app.DesciptionViewerLabel.Text = "Description: " + dataSet.SkinDataSet.desc;
            app.RunAllButton.Enable = "on";
            app.SensorNumberDropDown.Items = cellstr(string(1:dataSet.SkinDataSet.sensNum));
            app.unitsSet = 0; %Units for the heatmap only need to be set once
            app.unitsSet2 = 0; %Units for the heatmap only need to be set once
            updatePanels(app);
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
            %updatePanels(app);

            %%%% Check if true data was selected %%%%
            runTrue = 0;
            if app.TrueDataDropDown.Value ~= ""
                runTrue  = 1;
                app.trueData = load("SkinTruePositions\" + app.TrueDataDropDown.Value);
            end

            %%%% Plot position for each sensor on large graph %%%%%

            borderLim = 0.2;
            z1 = [0, app.loadSet.SkinDataSet.widthIn];
            z2 = [app.loadSet.SkinDataSet.lengthIn, app.loadSet.SkinDataSet.widthIn];
            z3 = [app.loadSet.SkinDataSet.lengthIn, 0];
            z4 = [0,0];

            plot(app.UIAxes, [z1(1),z2(1)], [z1(2),z2(2)], 'r', 'LineWidth',2);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, [z2(1),z3(1)], [z2(2),z3(2)], 'r', 'LineWidth',2);
            plot(app.UIAxes, [z3(1),z4(1)], [z3(2),z4(2)], 'r', 'LineWidth',2);
            plot(app.UIAxes, [z4(1),z1(1)], [z4(2),z1(2)], 'r', 'LineWidth',2);

            ylim(app.UIAxes, [-borderLim, app.loadSet.SkinDataSet.widthIn + borderLim]);
            xlim(app.UIAxes, [-borderLim, app.loadSet.SkinDataSet.lengthIn + borderLim]);
            axis(app.UIAxes, 'equal');

            %Plot each location
            itemSet{1} = "";
            app.ErrorListBox.Enable = "off";
            for i = 1:app.loadSet.SkinDataSet.sensNum
                app.SensorNumberDropDown.Value = app.SensorNumberDropDown.Items{i};
                sensorPos = updatePanels(app);
                scatter(app.UIAxes, sensorPos(1), sensorPos(2), 100, 'k*','LineWidth',1.5);
                if runTrue
                    scatter(app.UIAxes, app.trueData.trueSet.posReal(1,i), app.trueData.trueSet.posReal(2,i), 'b', 'filled');
                    plot(app.UIAxes, [sensorPos(1), app.trueData.trueSet.posReal(1,i)], [sensorPos(2),app.trueData.trueSet.posReal(2,i)], 'r--');
                    difference = [abs(sensorPos(1) - app.trueData.trueSet.posReal(1,i)), abs(sensorPos(2) - app.trueData.trueSet.posReal(2,i))];
                    itemSet{i} = convertStringsToChars("Sensor " + i + ": " + norm(difference) + " in");
                    app.ErrorListBox.Items = itemSet;
                end
                pause(.05);
            end


            ylim(app.UIAxes, [-borderLim, app.loadSet.SkinDataSet.widthIn + borderLim]);
            xlim(app.UIAxes, [-borderLim, app.loadSet.SkinDataSet.lengthIn + borderLim]);
            axis(app.UIAxes, 'equal');


            app.ErrorListBox.Enable = "on";
            app.ContourCheckBox.Enable = "on";
            app.ContourCheckBox.Value = 1;
            hold(app.UIAxes, 'off');
        end

        % Value changed function: SensorNumberDropDown
        function SensorNumberDropDownValueChanged(app, event)
            value = app.SensorNumberDropDown.Value;
            updatePanels(app);
        end

        % Clicked callback: TrueDataDropDown
        function TrueDataDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            listings = dir("SkinTruePositions");
            itemSet = {};
            itemSet{1} = '';
            for i = 3:size(listings, 1)
                itemSet{i-1} = listings(i).name;
            end
            app.TrueDataDropDown.Items = itemSet;
        end

        % Value changed function: ErrorListBox
        function ErrorListBoxValueChanged(app, event)
            value = app.ErrorListBox.Value;
            [~, indices] = ismember(value, app.ErrorListBox.Items);

            %Call the highlighter to change
            hold(app.UIAxes, 'on');
            if ~isempty(app.highlightSensor)
                delete(app.highlightSensor);
            end
            app.highlightSensor = scatter(app.UIAxes, app.trueData.trueSet.posReal(1,indices), app.trueData.trueSet.posReal(2,indices), 500, 'mp', 'filled');    
            hold(app.UIAxes, 'off');

            %Call the heatmaps to change
            app.SensorNumberDropDown.Value = app.SensorNumberDropDown.Items{indices};
            updatePanels(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'True Position vs Predicted Position')
            xlabel(app.UIAxes, 'X (inch)')
            ylabel(app.UIAxes, 'Y (inch)')
            zlabel(app.UIAxes, 'Z')
            app.SensorLocatorLabel = uilabel(app.UIFigure);
            app.SensorLocatorLabel.Text = 'Sensor Locator';
            app.DataNameViewerLabel = uilabel(app.UIFigure);
            app.DataNameViewerLabel.Text = 'Data Name Viewer';
            app.DesciptionViewerLabel = uilabel(app.UIFigure);
            app.DesciptionViewerLabel.Text = 'Desciption Viewer';
            app.SensorNumberDropDownLabel = uilabel(app.UIFigure);
            app.SensorNumberDropDownLabel.Text = 'Sensor Number';
            app.SensorNumberDropDown = uidropdown(app.UIFigure);
            app.SensorNumberDropDown.Items = {};
            app.SensorNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @SensorNumberDropDownValueChanged, true);
            app.SensorNumberDropDown.Value = {};
            app.RunAllButton = uibutton(app.UIFigure, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Enable = 'off';
            app.RunAllButton.Text = 'Run All';
            app.RawDataPanel = uipanel(app.UIFigure);
            app.RawDataPanel.Title = 'Raw Data';
            app.UpscaledDataPanel = uipanel(app.UIFigure);
            app.UpscaledDataPanel.Title = 'Upscaled Data';
            app.PixelsperInchEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsperInchEditFieldLabel.Text = 'Pixels per Inch';
            app.PixelsperInchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelsperInchEditField.Value = 10;
            app.TrueDataDropDownLabel = uilabel(app.UIFigure);
            app.TrueDataDropDownLabel.Text = 'True Data';
            app.TrueDataDropDown = uidropdown(app.UIFigure);
            app.TrueDataDropDown.Items = {};
            app.TrueDataDropDown.ClickedFcn = createCallbackFcn(app, @TrueDataDropDownClicked, true);
            app.TrueDataDropDown.Value = {};
            app.ErrorListBoxLabel = uilabel(app.UIFigure);
            app.ErrorListBoxLabel.Text = 'Error';
            app.ErrorListBox = uilistbox(app.UIFigure);
            app.ErrorListBox.Items = {};
            app.ErrorListBox.ValueChangedFcn = createCallbackFcn(app, @ErrorListBoxValueChanged, true);
            app.ErrorListBox.Value = {};
            app.ContourCheckBox = uicheckbox(app.UIFigure);
            app.ContourCheckBox.Enable = 'off';
            app.ContourCheckBox.Text = 'Contour';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SensorLocator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end